classdef AldyGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RunningLampLabel              matlab.ui.control.Label
        RunningLamp                   matlab.ui.control.Lamp
        JoggingEnabledLampLabel       matlab.ui.control.Label
        JoggingEnabledLamp            matlab.ui.control.Lamp
        eStopButton                   matlab.ui.control.Button
        ModeSwitchLabel               matlab.ui.control.Label
        ModeSwitch                    matlab.ui.control.ToggleSwitch
        L1Slider                      matlab.ui.control.Slider
        L2Slider                      matlab.ui.control.Slider
        L3Slider                      matlab.ui.control.Slider
        L4Slider                      matlab.ui.control.Slider
        L5Slider                      matlab.ui.control.Slider
        L6Slider                      matlab.ui.control.Slider
        YplusButton                   matlab.ui.control.Button
        XminusButton                  matlab.ui.control.Button
        XplusButton                   matlab.ui.control.Button
        YminusButton                  matlab.ui.control.Button
        ZplusButton                   matlab.ui.control.Button
        ZminusButton                  matlab.ui.control.Button
        RobotTransformLabel           matlab.ui.control.Label
        YLabel                        matlab.ui.control.Label
        XLabel                        matlab.ui.control.Label
        ZLabel                        matlab.ui.control.Label
        robotTransformY               matlab.ui.control.Label
        robotTransformX               matlab.ui.control.Label
        robotTransformZ               matlab.ui.control.Label
        PLabel                        matlab.ui.control.Label
        RLabel                        matlab.ui.control.Label
        YLabel_2                      matlab.ui.control.Label
        robotTransformPitch           matlab.ui.control.Label
        robotTransformRoll            matlab.ui.control.Label
        robotTransformYaw             matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        L1plusButton                  matlab.ui.control.Button
        L1minusButton                 matlab.ui.control.Button
        L2plusButton                  matlab.ui.control.Button
        L2minusButton                 matlab.ui.control.Button
        L3plusButton                  matlab.ui.control.Button
        L3minusButton                 matlab.ui.control.Button
        L4plusButton                  matlab.ui.control.Button
        L4minusButton                 matlab.ui.control.Button
        L5plusButton                  matlab.ui.control.Button
        L5minusButton                 matlab.ui.control.Button
        L6plusButton                  matlab.ui.control.Button
        L6minusButton                 matlab.ui.control.Button
        LinkJogStepdegEditFieldLabel  matlab.ui.control.Label
        LinkJogStepdegEditField       matlab.ui.control.NumericEditField
        XYZJogStepmmEditFieldLabel    matlab.ui.control.Label
        XYZJogStepmmEditField         matlab.ui.control.NumericEditField
        RobotSelectSwitchLabel        matlab.ui.control.Label
        RobotSelectSwitch             matlab.ui.control.RockerSwitch
        personYplusButton             matlab.ui.control.Button
        personXminusButton            matlab.ui.control.Button
        personXplusButton             matlab.ui.control.Button
        personYminusButton            matlab.ui.control.Button
        PersonControlsLabel           matlab.ui.control.Label
        PersonStepmmEditFieldLabel    matlab.ui.control.Label
        PersonStepmmEditField         matlab.ui.control.NumericEditField
        LightCurtainSwitchLabel       matlab.ui.control.Label
        LightCurtainSwitch            matlab.ui.control.Switch
    end

    
    properties (Access = private)
        robotSelect = false;
        idle = true; % Description
        eStop = false;
        bot1P;
        bot1T;
        env;
    end
    
    methods (Access = public)
        
        function app = updateGUI(app, env)
            app.env = env;
            if app.env.eStop == true
                app.eStopButton.BackgroundColor = [1,0,0];
                app.eStop = true;
                app.idle = true;
            else
                app.eStopButton.BackgroundColor = [0,1,0];
                app.eStop = false;
            end
            
            %update gui transform variables from env
            app.bot1P = env.AldyBaggerBot.robot.model.getpos;
            app.bot1T = env.AldyBaggerBot.robot.model.fkine(app.bot1P);
            app.robotTransformX.Text = num2str(app.bot1T(1, 4));
            app.robotTransformY.Text = num2str(app.bot1T(2, 4));
            app.robotTransformZ.Text = num2str(app.bot1T(3, 4));
            rpy = rad2deg(tr2rpy(app.bot1T));
            app.robotTransformRoll.Text = num2str(rpy(1));
            app.robotTransformPitch.Text = num2str(rpy(2));
            app.robotTransformYaw.Text = num2str(rpy(3));
            
            %update slider positions
            pDegrees = rad2deg(app.bot1P);
            app.L1Slider.Value = pDegrees(1);
            app.L2Slider.Value = pDegrees(2);
            app.L3Slider.Value = pDegrees(3);
            app.L4Slider.Value = pDegrees(4);
            app.L5Slider.Value = pDegrees(5);
            app.L6Slider.Value = pDegrees(6);
            
            %update status lamps
            if env.idle == true
                app.RunningLamp.Color = [1,0,0];
            else
                app.RunningLamp.Color = [0,1,0];
                app.JoggingEnabledLamp.Color = [1,0,0];
            end
            
        end
        
        function env = updateEnv(app, env)
            env.idle = app.idle;
            env.eStop = app.eStop;
            try app.LightCurtainSwitch.Value == 'Enabled';
                env.lightCurtainEnabled = true;
            catch
                env.lightCurtainEnabled = false;
            end
            
            env.Person.body.base = app.env.Person.body.base;
            env.Person.body.animate(0);
            if app.eStop == false
                if app.idle == true
                    %jog mode
                    app.JoggingEnabledLamp.Color = [0,1,0];
                    
                    %sliders
                    p = [app.L1Slider.Value, app.L2Slider.Value, app.L3Slider.Value, app.L4Slider.Value, app.L5Slider.Value, app.L6Slider.Value];
                    p = deg2rad(p);
                    env.AldyBaggerBot.robot.model.animate(p);
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, env)
            app.env = env;
            app.bot1P = env.AldyBaggerBot.robot.model.getpos;
            app.bot1T = env.AldyBaggerBot.robot.model.fkine(app.bot1P);
            
            %set slider limits
            limits = rad2deg(env.AldyBaggerBot.robot.model.qlim);
            app.L1Slider.Limits = limits(1, :);
            app.L2Slider.Limits = limits(2, :);
            app.L3Slider.Limits = limits(3, :);
            app.L4Slider.Limits = limits(4, :);
            app.L5Slider.Limits = limits(5, :);
            app.L6Slider.Limits = limits(6, :);
            
            pDegrees = rad2deg(app.bot1P);
            app.L1Slider.Value = pDegrees(1);
            app.L2Slider.Value = pDegrees(2);
            app.L3Slider.Value = pDegrees(3);
            app.L4Slider.Value = pDegrees(4);
            app.L5Slider.Value = pDegrees(5);
            app.L6Slider.Value = pDegrees(6);
        end

        % Button pushed function: eStopButton
        function eStopButtonPushed(app, event)
            if app.eStop == false
                app.eStopButton.BackgroundColor = [1,0,0];
                app.JoggingEnabledLamp.Color = [1,0,0];
                app.eStop = true;
                app.idle = true;
            else
                app.eStopButton.BackgroundColor = [0,1,0];
                app.eStop = false;
            end        
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.eStop == false
                app.idle = false;
            end
        end

        % Button pushed function: L1plusButton
        function L1plusButtonPushed(app, event)
            app.L1Slider.Value = app.L1Slider.Value + app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L1minusButton
        function L1minusButtonPushed(app, event)
            app.L1Slider.Value = app.L1Slider.Value - app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L2plusButton
        function L2plusButtonPushed(app, event)
            app.L2Slider.Value = app.L2Slider.Value + app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L2minusButton
        function L2minusButtonPushed(app, event)
            app.L2Slider.Value = app.L2Slider.Value - app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L3plusButton
        function L3plusButtonPushed(app, event)
            app.L3Slider.Value = app.L3Slider.Value + app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L3minusButton
        function L3minusButtonPushed(app, event)
            app.L3Slider.Value = app.L3Slider.Value - app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L4plusButton
        function L4plusButtonPushed(app, event)
            app.L4Slider.Value = app.L4Slider.Value + app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L4minusButton
        function L4minusButtonPushed(app, event)
            app.L4Slider.Value = app.L4Slider.Value - app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L5plusButton
        function L5plusButtonPushed(app, event)
            app.L5Slider.Value = app.L5Slider.Value + app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L5minusButton
        function L5minusButtonPushed(app, event)
            app.L5Slider.Value = app.L5Slider.Value - app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L6plusButton
        function L6plusButtonPushed(app, event)
            app.L6Slider.Value = app.L6Slider.Value + app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: L6minusButton
        function L6minusButtonPushed(app, event)
            app.L6Slider.Value = app.L6Slider.Value - app.LinkJogStepdegEditField.Value;
        end

        % Button pushed function: YplusButton
        function YplusButtonPushed(app, event)
            newT = app.bot1T;
            newT(2, 4) = newT(2, 4) + app.XYZJogStepmmEditField.Value/1000;
            try newP = app.env.AldyBaggerBot.robot.model.ikcon(newT, app.bot1P);
                app.L1Slider.Value = rad2deg(newP(1));
                app.L2Slider.Value = rad2deg(newP(2));
                app.L3Slider.Value = rad2deg(newP(3));
                app.L4Slider.Value = rad2deg(newP(4));
                app.L5Slider.Value = rad2deg(newP(5));
                app.L6Slider.Value = rad2deg(newP(6));
            catch ME
                
            end
        end

        % Button pushed function: YminusButton
        function YminusButtonPushed(app, event)
            newT = app.bot1T;
            newT(2, 4) = newT(2, 4) - app.XYZJogStepmmEditField.Value/1000;
            try newP = app.env.AldyBaggerBot.robot.model.ikcon(newT, app.bot1P);
                app.L1Slider.Value = rad2deg(newP(1));
                app.L2Slider.Value = rad2deg(newP(2));
                app.L3Slider.Value = rad2deg(newP(3));
                app.L4Slider.Value = rad2deg(newP(4));
                app.L5Slider.Value = rad2deg(newP(5));
                app.L6Slider.Value = rad2deg(newP(6));
            catch ME
                
            end
        end

        % Button pushed function: XminusButton
        function XminusButtonPushed(app, event)
            newT = app.bot1T;
            newT(1, 4) = newT(1, 4) - app.XYZJogStepmmEditField.Value/1000;
            try newP = app.env.AldyBaggerBot.robot.model.ikcon(newT, app.bot1P);
                app.L1Slider.Value = rad2deg(newP(1));
                app.L2Slider.Value = rad2deg(newP(2));
                app.L3Slider.Value = rad2deg(newP(3));
                app.L4Slider.Value = rad2deg(newP(4));
                app.L5Slider.Value = rad2deg(newP(5));
                app.L6Slider.Value = rad2deg(newP(6));
            catch ME
                
            end
        end

        % Button pushed function: XplusButton
        function XplusButtonPushed(app, event)
            newT = app.bot1T;
            newT(1, 4) = newT(1, 4) + app.XYZJogStepmmEditField.Value/1000;
            try newP = app.env.AldyBaggerBot.robot.model.ikcon(newT, app.bot1P);
                app.L1Slider.Value = rad2deg(newP(1));
                app.L2Slider.Value = rad2deg(newP(2));
                app.L3Slider.Value = rad2deg(newP(3));
                app.L4Slider.Value = rad2deg(newP(4));
                app.L5Slider.Value = rad2deg(newP(5));
                app.L6Slider.Value = rad2deg(newP(6));
            catch ME
                
            end
        end

        % Button pushed function: ZplusButton
        function ZplusButtonPushed(app, event)
            newT = app.bot1T;
            newT(3, 4) = newT(3, 4) + app.XYZJogStepmmEditField.Value/1000;
            try newP = app.env.AldyBaggerBot.robot.model.ikcon(newT, app.bot1P);
                app.L1Slider.Value = rad2deg(newP(1));
                app.L2Slider.Value = rad2deg(newP(2));
                app.L3Slider.Value = rad2deg(newP(3));
                app.L4Slider.Value = rad2deg(newP(4));
                app.L5Slider.Value = rad2deg(newP(5));
                app.L6Slider.Value = rad2deg(newP(6));
            catch ME
                
            end
        end

        % Button pushed function: ZminusButton
        function ZminusButtonPushed(app, event)
            newT = app.bot1T;
            newT(3, 4) = newT(3, 4) - app.XYZJogStepmmEditField.Value/1000;
            try newP = app.env.AldyBaggerBot.robot.model.ikcon(newT, app.bot1P);
                app.L1Slider.Value = rad2deg(newP(1));
                app.L2Slider.Value = rad2deg(newP(2));
                app.L3Slider.Value = rad2deg(newP(3));
                app.L4Slider.Value = rad2deg(newP(4));
                app.L5Slider.Value = rad2deg(newP(5));
                app.L6Slider.Value = rad2deg(newP(6));
            catch ME
                
            end
        end

        % Button pushed function: personYplusButton
        function personYplusButtonPushed(app, event)
            app.env.Person.body.base(2, 4) = app.env.Person.body.base(2, 4) + app.PersonStepmmEditField.Value/1000;
        end

        % Button pushed function: personXminusButton
        function personXminusButtonPushed(app, event)
            app.env.Person.body.base(1, 4) = app.env.Person.body.base(1, 4) - app.PersonStepmmEditField.Value/1000;
        end

        % Button pushed function: personXplusButton
        function personXplusButtonPushed(app, event)
            app.env.Person.body.base(1, 4) = app.env.Person.body.base(1, 4) + app.PersonStepmmEditField.Value/1000;
        end

        % Button pushed function: personYminusButton
        function personYminusButtonPushed(app, event)
            app.env.Person.body.base(2, 4) = app.env.Person.body.base(2, 4) - app.PersonStepmmEditField.Value/1000;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RunningLampLabel = uilabel(app.UIFigure);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.UIFigure);
            app.JoggingEnabledLampLabel = uilabel(app.UIFigure);
            app.JoggingEnabledLampLabel.Text = 'Jogging Enabled';
            app.JoggingEnabledLamp = uilamp(app.UIFigure);
            app.eStopButton = uibutton(app.UIFigure, 'push');
            app.eStopButton.ButtonPushedFcn = createCallbackFcn(app, @eStopButtonPushed, true);
            app.eStopButton.Icon = 'eStop_Icon.png';
            app.eStopButton.Text = '';
            app.ModeSwitchLabel = uilabel(app.UIFigure);
            app.ModeSwitchLabel.Text = 'Mode';
            app.ModeSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ModeSwitch.Items = {'Normal', 'Programming'};
            app.ModeSwitch.Value = 'Normal';
            app.L1Slider = uislider(app.UIFigure);
            app.L2Slider = uislider(app.UIFigure);
            app.L3Slider = uislider(app.UIFigure);
            app.L4Slider = uislider(app.UIFigure);
            app.L5Slider = uislider(app.UIFigure);
            app.L6Slider = uislider(app.UIFigure);
            app.YplusButton = uibutton(app.UIFigure, 'push');
            app.YplusButton.ButtonPushedFcn = createCallbackFcn(app, @YplusButtonPushed, true);
            app.YplusButton.Text = '+Y';
            app.XminusButton = uibutton(app.UIFigure, 'push');
            app.XminusButton.ButtonPushedFcn = createCallbackFcn(app, @XminusButtonPushed, true);
            app.XminusButton.Text = '-X';
            app.XplusButton = uibutton(app.UIFigure, 'push');
            app.XplusButton.ButtonPushedFcn = createCallbackFcn(app, @XplusButtonPushed, true);
            app.XplusButton.Text = '+X';
            app.YminusButton = uibutton(app.UIFigure, 'push');
            app.YminusButton.ButtonPushedFcn = createCallbackFcn(app, @YminusButtonPushed, true);
            app.YminusButton.Text = '-Y';
            app.ZplusButton = uibutton(app.UIFigure, 'push');
            app.ZplusButton.ButtonPushedFcn = createCallbackFcn(app, @ZplusButtonPushed, true);
            app.ZplusButton.Text = '+Z';
            app.ZminusButton = uibutton(app.UIFigure, 'push');
            app.ZminusButton.ButtonPushedFcn = createCallbackFcn(app, @ZminusButtonPushed, true);
            app.ZminusButton.Text = '-Z';
            app.RobotTransformLabel = uilabel(app.UIFigure);
            app.RobotTransformLabel.Text = 'Robot Transform';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y:';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X:';
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z:';
            app.robotTransformY = uilabel(app.UIFigure);
            app.robotTransformY.Text = '0.000';
            app.robotTransformX = uilabel(app.UIFigure);
            app.robotTransformX.Text = '0.000';
            app.robotTransformZ = uilabel(app.UIFigure);
            app.robotTransformZ.Text = '0.000';
            app.PLabel = uilabel(app.UIFigure);
            app.PLabel.Text = 'P:';
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Text = 'R:';
            app.YLabel_2 = uilabel(app.UIFigure);
            app.YLabel_2.Text = 'Y:';
            app.robotTransformPitch = uilabel(app.UIFigure);
            app.robotTransformPitch.Text = '0.000';
            app.robotTransformRoll = uilabel(app.UIFigure);
            app.robotTransformRoll.Text = '0.000';
            app.robotTransformYaw = uilabel(app.UIFigure);
            app.robotTransformYaw.Text = '0.000';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.L1plusButton = uibutton(app.UIFigure, 'push');
            app.L1plusButton.ButtonPushedFcn = createCallbackFcn(app, @L1plusButtonPushed, true);
            app.L1plusButton.Text = 'L1+';
            app.L1minusButton = uibutton(app.UIFigure, 'push');
            app.L1minusButton.ButtonPushedFcn = createCallbackFcn(app, @L1minusButtonPushed, true);
            app.L1minusButton.Text = 'L1-';
            app.L2plusButton = uibutton(app.UIFigure, 'push');
            app.L2plusButton.ButtonPushedFcn = createCallbackFcn(app, @L2plusButtonPushed, true);
            app.L2plusButton.Text = 'L2+';
            app.L2minusButton = uibutton(app.UIFigure, 'push');
            app.L2minusButton.ButtonPushedFcn = createCallbackFcn(app, @L2minusButtonPushed, true);
            app.L2minusButton.Text = 'L2-';
            app.L3plusButton = uibutton(app.UIFigure, 'push');
            app.L3plusButton.ButtonPushedFcn = createCallbackFcn(app, @L3plusButtonPushed, true);
            app.L3plusButton.Text = 'L3+';
            app.L3minusButton = uibutton(app.UIFigure, 'push');
            app.L3minusButton.ButtonPushedFcn = createCallbackFcn(app, @L3minusButtonPushed, true);
            app.L3minusButton.Text = 'L3-';
            app.L4plusButton = uibutton(app.UIFigure, 'push');
            app.L4plusButton.ButtonPushedFcn = createCallbackFcn(app, @L4plusButtonPushed, true);
            app.L4plusButton.Text = 'L4+';
            app.L4minusButton = uibutton(app.UIFigure, 'push');
            app.L4minusButton.ButtonPushedFcn = createCallbackFcn(app, @L4minusButtonPushed, true);
            app.L4minusButton.Text = 'L4-';
            app.L5plusButton = uibutton(app.UIFigure, 'push');
            app.L5plusButton.ButtonPushedFcn = createCallbackFcn(app, @L5plusButtonPushed, true);
            app.L5plusButton.Text = 'L5+';
            app.L5minusButton = uibutton(app.UIFigure, 'push');
            app.L5minusButton.ButtonPushedFcn = createCallbackFcn(app, @L5minusButtonPushed, true);
            app.L5minusButton.Text = 'L5-';
            app.L6plusButton = uibutton(app.UIFigure, 'push');
            app.L6plusButton.ButtonPushedFcn = createCallbackFcn(app, @L6plusButtonPushed, true);
            app.L6plusButton.Text = 'L6+';
            app.L6minusButton = uibutton(app.UIFigure, 'push');
            app.L6minusButton.ButtonPushedFcn = createCallbackFcn(app, @L6minusButtonPushed, true);
            app.L6minusButton.Text = 'L6-';
            app.LinkJogStepdegEditFieldLabel = uilabel(app.UIFigure);
            app.LinkJogStepdegEditFieldLabel.Text = 'Link Jog Step (deg):';
            app.LinkJogStepdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.LinkJogStepdegEditField.Value = 5;
            app.XYZJogStepmmEditFieldLabel = uilabel(app.UIFigure);
            app.XYZJogStepmmEditFieldLabel.Text = 'XYZ Jog Step (mm):';
            app.XYZJogStepmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.XYZJogStepmmEditField.Value = 5;
            app.RobotSelectSwitchLabel = uilabel(app.UIFigure);
            app.RobotSelectSwitchLabel.Text = 'Robot Select';
            app.RobotSelectSwitch = uiswitch(app.UIFigure, 'rocker');
            app.RobotSelectSwitch.Items = {'Kuka', 'UR3'};
            app.RobotSelectSwitch.Value = 'Kuka';
            app.personYplusButton = uibutton(app.UIFigure, 'push');
            app.personYplusButton.ButtonPushedFcn = createCallbackFcn(app, @personYplusButtonPushed, true);
            app.personYplusButton.Text = '+Y';
            app.personXminusButton = uibutton(app.UIFigure, 'push');
            app.personXminusButton.ButtonPushedFcn = createCallbackFcn(app, @personXminusButtonPushed, true);
            app.personXminusButton.Text = '-X';
            app.personXplusButton = uibutton(app.UIFigure, 'push');
            app.personXplusButton.ButtonPushedFcn = createCallbackFcn(app, @personXplusButtonPushed, true);
            app.personXplusButton.Text = '+X';
            app.personYminusButton = uibutton(app.UIFigure, 'push');
            app.personYminusButton.ButtonPushedFcn = createCallbackFcn(app, @personYminusButtonPushed, true);
            app.personYminusButton.Text = '-Y';
            app.PersonControlsLabel = uilabel(app.UIFigure);
            app.PersonControlsLabel.Text = 'Person Controls';
            app.PersonStepmmEditFieldLabel = uilabel(app.UIFigure);
            app.PersonStepmmEditFieldLabel.Text = 'Person Step (mm):';
            app.PersonStepmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.PersonStepmmEditField.Value = 10;
            app.LightCurtainSwitchLabel = uilabel(app.UIFigure);
            app.LightCurtainSwitchLabel.Text = 'Light Curtain';
            app.LightCurtainSwitch = uiswitch(app.UIFigure, 'slider');
            app.LightCurtainSwitch.Items = {'Disabled', 'Enabled'};
            app.LightCurtainSwitch.Value = 'Enabled';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AldyGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end